classdef main_jiaohu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button_2          matlab.ui.control.RadioButton
        Button_3          matlab.ui.control.RadioButton
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        Button_4          matlab.ui.control.RadioButton
        Button_5          matlab.ui.control.RadioButton
        Button_6          matlab.ui.control.RadioButton
        ButtonGroup_3     matlab.ui.container.ButtonGroup
        Button_7          matlab.ui.control.RadioButton
        Button_8          matlab.ui.control.RadioButton
        Button            matlab.ui.control.Button
        TabGroup_2        matlab.ui.container.TabGroup
        Tab_2             matlab.ui.container.Tab
        EditFieldLabel    matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        TextAreaLabel     matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Tab_3             matlab.ui.container.Tab
        UITable           matlab.ui.control.Table
        Panel             matlab.ui.container.Panel
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        CheckBox          matlab.ui.control.CheckBox
        Label_8           matlab.ui.control.Label
        Tab_4             matlab.ui.container.Tab
        Panel_2           matlab.ui.container.Panel
        Label_2           matlab.ui.control.Label
        EditField_7       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_8       matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        XLabel            matlab.ui.control.Label
        XEditField_7      matlab.ui.control.NumericEditField
        YLabel            matlab.ui.control.Label
        YEditField_6      matlab.ui.control.NumericEditField
        Button_9          matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value==1
                clc
                [file,path]= uigetfile('*.xls');
                if isequal(file,0)
                    disp('请选择文件');
                else
                    disp(['请重新选择 ', fullfile(path,file)]);
                end
                app.Label_8.Text=strcat(path,'\',file);
            else
            end
        end

        % Button pushed function: Button
        function Button_9Pushed(app, event)
            %% 参数设定
            if app.Button_6.Value==1
                mian_value.pan_duan_bia0_zhuan_hua=1;
            else
                mian_value.pan_duan_bia0_zhuan_hua=2;
            end
            if app.Button_4.Value==1
                mian_value.pan_duan_xuanzhuang=1;
            elseif app.Button_5.Value==1
                mian_value.pan_duan_xuanzhuang=2;
            else
                mian_value.pan_duan_xuanzhuang=3;
            end
            if app.Button_7.Value==1
                mian_value.c=1;
            else
                mian_value.c=2;
            end
            a_num.min=app.EditField_2.Value;
            a_num.max=app.EditField_3.Value;
            a_num.star=app.EditField_4.Value;
            if a_num.min==0 && a_num.max==0&& a_num.star==0
                a_num=fun_num(app.Label_8.Text);
            end
            %% 运行
            msgfig = msgbox('开始运行了');
            uiwait(msgfig,2)
            close all
            [R,RUV]=finish_3_30_1(app.Label_8.Text,mian_value,a_num);%主函数
            if RUV>0
                app.Label.Text='2种物质为联合作用';
                app.EditField.Value=num2str(RUV);
                display(strcat('RUV=',num2str(RUV),'%   2种物质为联合作用'))
            elseif RUV==0
                app.Label.Text='2种物质为无相关作用';
                app.EditField.Value=num2str(RUV);
                display(strcat('RUV=',num2str(RUV),'%   2种物质为无相关作用'))
            else
                app.Label.Text='2种物质为拮抗作用';
                app.EditField.Value=num2str(RUV);
                display(strcat('RUV=',num2str(RUV),'%   2种物质为拮抗作用'))
            end
%             app.TextArea.Value=R.gof12;
            cellArrayText{1}='-------------分割线-------------------';
            cellArrayText{2} =strcat('浓度相加的拟合r^2=    ',num2str(R.IAgof.rsquare));
            cellArrayText{3}='浓度相加的拟合方程';
%             app.TextArea.Tag=R.IAfitresult;
            disp(R.IAfitresult)
            cellArrayText{4}='-------------分割线-------------------';
            cellArrayText{5}=strcat('浓度联合的最佳拟合r^2=    ',num2str(R.gof12.rsquare));
            cellArrayText{6}='浓度联合的最佳拟合方程';
%             app.TextArea.Value=R.fitresult112;
            disp(R.fitresult112)
            cellArrayText{7}='-------------分割线-------------------';
            cellArrayText{8}=strcat('拟合最佳方程=    ',num2str(R.best_r));
            cellArrayText{9}=strcat('浓度联合的迭代拟合=    ',num2str(R.gof_find.rsquare));
            app.TextArea.Value=cellArrayText;
            arr={'浓度相加的拟合方程';'浓度联合的最佳拟合方程'};
            s = {R.IAfitresult; R.fitresult112};
            tdata = table(arr,s,'VariableNames',{'Array','Structure'});
%         app.UITable.ColumnName='Array';
%         app.UITable.Data= s;
%         app.UITable.ColumnName='Structure';
           app.UITable.Data= tdata;
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            value_str.aaaa=app.EditField_7.Value;
            value_str.ccc=app.YEditField_6.Value;
            value_str.bbb=app.XEditField_7.Value; %x粥
            value_str.jjj=app.EditField_8.Value;
            equit_line(value_str)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '交互模型运算';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = '变量浓度是否需要标准化';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '不需要';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '需要';
            app.ButtonGroup_2 = uibuttongroup(app.Tab);
            app.ButtonGroup_2.Title = '数据是否需要进行反转';
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = '向上反转';
            app.Button_5 = uiradiobutton(app.ButtonGroup_2);
            app.Button_5.Text = '旋转90';
            app.Button_6 = uiradiobutton(app.ButtonGroup_2);
            app.Button_6.Text = '不进行旋转';
            app.Button_6.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.Tab);
            app.ButtonGroup_3.Title = '结果是否完全展示';
            app.Button_7 = uiradiobutton(app.ButtonGroup_3);
            app.Button_7.Text = '是';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.ButtonGroup_3);
            app.Button_8.Text = '否';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button.Text = '运行';
            app.TabGroup_2 = uitabgroup(app.Tab);
            app.Tab_2 = uitab(app.TabGroup_2);
            app.Tab_2.Title = '结果展示';
            app.EditFieldLabel = uilabel(app.Tab_2);
            app.EditFieldLabel.Text = '联合作用';
            app.EditField = uieditfield(app.Tab_2, 'text');
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '。';
            app.TextAreaLabel = uilabel(app.Tab_2);
            app.TextAreaLabel.Text = '公式结果';
            app.TextArea = uitextarea(app.Tab_2);
            app.Tab_3 = uitab(app.TabGroup_2);
            app.Tab_3.Title = '方程结果';
            app.UITable = uitable(app.Tab_3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '数据信息输入';
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '最小值';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '最大值';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = '起始值';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.CheckBox = uicheckbox(app.Tab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '数据文件选择';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = '空';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '等高线生成';
            app.Panel_2 = uipanel(app.Tab_4);
            app.Panel_2.Title = '初始数据信息输入';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '需要寻找的界限值';
            app.EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_7.Value = 90;
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '是否展示极值点的位置';
            app.EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_8.Limits = [1 2];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.Value = 1;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '默认值为 [0 1]';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '默认值为 [0 1]';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '设定想要寻找的界限值';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '1展示 2 不显示';
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = '范围X轴';
            app.XEditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.XEditField_7.Limits = [0 1];
            app.XEditField_7.Value = 1;
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = '范围Y轴';
            app.YEditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.YEditField_6.Limits = [0 1];
            app.YEditField_6.Value = 1;
            app.Button_9 = uibutton(app.Tab_4, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Text = '等高线生成';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_jiaohu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end